classdef sound_localization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EndsoundlocalizationButton    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        StartsoundlocalizationButton  matlab.ui.control.Button
        MicrophoneSetupPanel          matlab.ui.container.Panel
        ThresholdSlider               matlab.ui.control.Slider
        ThresholdSliderLabel          matlab.ui.control.Label
        MicCpositionxyEditField       matlab.ui.control.NumericEditField
        MicCpositionxyEditFieldLabel  matlab.ui.control.Label
        xEditField_4                  matlab.ui.control.NumericEditField
        MicBpositionxyEditField       matlab.ui.control.NumericEditField
        MicBpositionxyEditFieldLabel  matlab.ui.control.Label
        xEditField_3                  matlab.ui.control.NumericEditField
        xEditField_2                  matlab.ui.control.NumericEditField
        MicApositionxyEditField       matlab.ui.control.NumericEditField
        MicApositionxyEditFieldLabel  matlab.ui.control.Label
        SamplerateHzEditField         matlab.ui.control.NumericEditField
        SamplerateHzEditFieldLabel    matlab.ui.control.Label
        MicrophoneNameEditField       matlab.ui.control.EditField
        MicrophoneNameEditFieldLabel  matlab.ui.control.Label
        WebSocketsetupPanel           matlab.ui.container.Panel
        EndServerButton               matlab.ui.control.Button
        PortEditField                 matlab.ui.control.NumericEditField
        PortEditFieldLabel            matlab.ui.control.Label
        StartServerButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        server % Description
        clientCode
        mic
        system_running
        fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartServerButton
        function StartServerButtonPushed(app, event)
            app.server = Server(app.PortEditField.Value); % Ändra till rätt port
            % Väntar tills en connection etablerats.
            app.TextArea.Value = "Waiting for server to connect...";
            while(size(app.server.Connections, 1)<1)
                pause(2);
            end  
            
            app.TextArea.Value = "Connected to client!";
            app.clientCode = app.server.Connections(1).HashCode;
            app.StartsoundlocalizationButton.Enable = "on";
        end

        % Button pushed function: StartsoundlocalizationButton
        function StartsoundlocalizationButtonPushed(app, event)
            app.TextArea.Value = "";
            app.system_running = true;
            app.EndsoundlocalizationButton.Enable = "on";
            app.StartsoundlocalizationButton.Enable = "off";
            app.fs = app.SamplerateHzEditField;
            frameLength = floor(48000/100); %app.fs = 480000
            mic_label = app.MicrophoneNameEditField.Value;
            app.mic = audioDeviceReader(app.fs, frameLength, "Device", string(mic_label) ,"NumChannels",3); % Se till att den hittas...
            speedofsound = 343; % 343 m/s sist jag kollade
            
            threshold = app.ThresholdSlider.Value;
            
            micApos = [app.MicApositionxyEditField.Value, app.xEditField_2.Value];
            micBpos = [app.MicBpositionxyEditField.Value, app.xEditField_3.Value];
            micCpos = [app.MicCpositionxyEditField.Value, app.xEditField_4.Value];

            STEs = [];
            peakIndex = [];
            macroFrame=0;
            previousSTE=0;
            i=1;

            % Real-time processing loop
            while app.system_running
            
                %If Front end-client disconnects, close down the program
                if(size(app.server.Connections, 1)<1)
                    app.system_running = false;
                    app.StartsoundlocalizationButton.Enable = "on";
                    app.EndsoundlocalizationButton.Enable = "off";
                    app.TextArea.Value = "Client disconnected!";

                    release(app.mic);
                    app.server.stop;
                    delete(app.server);
                    clear server
                    break
                end
            
                audio = app.mic();
                audioA = audio(:,1);
                audioB = audio(:,2);
                audioC = audio(:,3);
            
                % Compute STE for current frame (simplified, consider implementing a buffer for overlapping frames)
                currentSTE = sum(audioA.^2) / numel(audioA); % Simplified STE calculation, scalar
                STEs=[STEs; currentSTE];
                
                %calculate mean value of latest 10 frames of STEs
                if mod(i,10) == 0
                    macroFrame = mean(STEs(1:length(STEs)-1));
                end
                
                peakIndex = [peakIndex; currentSTE - macroFrame];
                
                % Check against threshold and prepare for localization
                if peakIndex(end) > threshold %peakIndex(end), currentSTE, mean(audioIn)      
                    pos = positionCalc(fs, speedofsound, micApos, micBpos, micCpos, audioA, audioB, audioC);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % De faktiska koordinaterna som skickas, 
                    % ni kanske vill ändra konverteringen
                    x = floor(1000*pos(1));
                    y = floor(1000*pos(2));

                    app.TextArea.Value = app.TextArea.Value + "\n" + "x: " + x + "y: " + y;
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
                    % Convert to string in the format X123Y456
            
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Skickar ut en string i formatet
                    % X123Y456, värt att tänka på när ni läser in
                    % i front ent
                    outputString = ['X' num2str(x) 'Y' num2str(y)];
                    app.server.sendTo(app.clientCode,outputString);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                end
                
                if size(peakIndex,1) > 20 % Keep only the last 2 seconds of information
                     peakIndex = peakIndex(2:end); % Adjust this to manage memory better
                     STEs = STEs(2:end);
                end
                i = i+1;
            end

        end

        % Button pushed function: EndsoundlocalizationButton
        function EndsoundlocalizationButtonPushed(app, event)
            app.system_running = false;
            app.StartsoundlocalizationButton.Enable = "on";
            app.EndsoundlocalizationButton.Enable = "off";

            release(app.mic);
            app.server.stop;
            delete(app.server);
            clear server
        end

        % Button pushed function: EndServerButton
        function EndServerButtonPushed(app, event)
            app.system_running = false;
            app.StartsoundlocalizationButton.Enable = "on";
            app.EndsoundlocalizationButton.Enable = "off";

            app.server.stop;
            delete(app.server);
            clear server
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Last Bounce')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.WebSocketsetupPanel = uipanel(app.UIFigure);
            app.WebSocketsetupPanel.Title = 'WebSocket setup';
            app.StartServerButton = uibutton(app.WebSocketsetupPanel, 'push');
            app.StartServerButton.ButtonPushedFcn = createCallbackFcn(app, @StartServerButtonPushed, true);
            app.StartServerButton.Text = 'Start Server';
            app.PortEditFieldLabel = uilabel(app.WebSocketsetupPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.WebSocketsetupPanel, 'numeric');
            app.EndServerButton = uibutton(app.WebSocketsetupPanel, 'push');
            app.EndServerButton.ButtonPushedFcn = createCallbackFcn(app, @EndServerButtonPushed, true);
            app.EndServerButton.Text = 'End Server';
            app.MicrophoneSetupPanel = uipanel(app.UIFigure);
            app.MicrophoneSetupPanel.Title = 'Microphone Setup';
            app.MicrophoneNameEditFieldLabel = uilabel(app.MicrophoneSetupPanel);
            app.MicrophoneNameEditFieldLabel.Text = 'Microphone  Name';
            app.MicrophoneNameEditField = uieditfield(app.MicrophoneSetupPanel, 'text');
            app.MicrophoneNameEditField.Value = 'Mikrofon (H5)';
            app.SamplerateHzEditFieldLabel = uilabel(app.MicrophoneSetupPanel);
            app.SamplerateHzEditFieldLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzEditField = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.SamplerateHzEditField.Value = 48000;
            app.MicApositionxyEditFieldLabel = uilabel(app.MicrophoneSetupPanel);
            app.MicApositionxyEditFieldLabel.Text = 'Mic A position: x, y';
            app.MicApositionxyEditField = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.xEditField_2 = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.xEditField_3 = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.MicBpositionxyEditFieldLabel = uilabel(app.MicrophoneSetupPanel);
            app.MicBpositionxyEditFieldLabel.Text = 'Mic B position: x, y';
            app.MicBpositionxyEditField = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.xEditField_4 = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.MicCpositionxyEditFieldLabel = uilabel(app.MicrophoneSetupPanel);
            app.MicCpositionxyEditFieldLabel.Text = 'Mic C position: x, y';
            app.MicCpositionxyEditField = uieditfield(app.MicrophoneSetupPanel, 'numeric');
            app.ThresholdSliderLabel = uilabel(app.MicrophoneSetupPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.MicrophoneSetupPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.Value = 0.5;
            app.StartsoundlocalizationButton = uibutton(app.UIFigure, 'push');
            app.StartsoundlocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsoundlocalizationButtonPushed, true);
            app.StartsoundlocalizationButton.Enable = 'off';
            app.StartsoundlocalizationButton.Text = 'Start sound localization';
            app.TextArea = uitextarea(app.UIFigure);
            app.EndsoundlocalizationButton = uibutton(app.UIFigure, 'push');
            app.EndsoundlocalizationButton.ButtonPushedFcn = createCallbackFcn(app, @EndsoundlocalizationButtonPushed, true);
            app.EndsoundlocalizationButton.Enable = 'off';
            app.EndsoundlocalizationButton.Text = 'End sound localization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sound_localization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end